classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label2                        matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        fig1                          matlab.ui.control.CheckBox
        fig2                          matlab.ui.control.CheckBox
        fig4                          matlab.ui.control.CheckBox
        fig7                          matlab.ui.control.CheckBox
        fig8                          matlab.ui.control.CheckBox
        fig9                          matlab.ui.control.CheckBox
        fig12                         matlab.ui.control.CheckBox
        fig13                         matlab.ui.control.CheckBox
        fig14                         matlab.ui.control.CheckBox
        LabelDropDown                 matlab.ui.control.Label
        language                      matlab.ui.control.DropDown
        fig11                         matlab.ui.control.CheckBox
        nMarket                       matlab.ui.control.NumericEditField
        CapTech                       matlab.ui.control.NumericEditField
        fig10                         matlab.ui.control.CheckBox
        nMarketOp                     matlab.ui.control.NumericEditField
        fig3                          matlab.ui.control.CheckBox
        fig5                          matlab.ui.control.CheckBox
        fig6                          matlab.ui.control.CheckBox
        fig15                         matlab.ui.control.CheckBox
        figBeh                        matlab.ui.control.CheckBox
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        save                          matlab.ui.control.CheckBox
        Optimize                      matlab.ui.control.Button
        LabelDiscreteKnob             matlab.ui.control.Label
        target                        matlab.ui.control.DiscreteKnob
        scenariodata                  matlab.ui.container.ButtonGroup
        KSS                           matlab.ui.control.RadioButton
        BMWi                          matlab.ui.control.RadioButton
        BioStromWaerme                matlab.ui.control.RadioButton
        ConstantPrices                matlab.ui.control.RadioButton
        PotentialCrops                matlab.ui.container.ButtonGroup
        CropConst                     matlab.ui.control.RadioButton
        CropNull                      matlab.ui.control.RadioButton
        BiomassUse                    matlab.ui.container.ButtonGroup
        BioUseHigh                    matlab.ui.control.RadioButton
        BioUseLow                     matlab.ui.control.RadioButton
        PotentialResidues             matlab.ui.container.ButtonGroup
        ResiduesHigh                  matlab.ui.control.RadioButton
        ResiduesLow                   matlab.ui.control.RadioButton
        ResiduesMedium                matlab.ui.control.RadioButton
        BiomasspriceincreaseaEditFieldLabel  matlab.ui.control.Label
        BiomassPriceIncrease          matlab.ui.control.EditField
        CO2price                      matlab.ui.container.ButtonGroup
        P50                           matlab.ui.control.RadioButton
        P100                          matlab.ui.control.RadioButton
        P200                          matlab.ui.control.RadioButton
        P300                          matlab.ui.control.RadioButton
        PowerPrice                    matlab.ui.container.ButtonGroup
        PP30                          matlab.ui.control.RadioButton
        PP50                          matlab.ui.control.RadioButton
        PP100                         matlab.ui.control.RadioButton
        PP150                         matlab.ui.control.RadioButton
        Incentives                    matlab.ui.container.Panel
        Investmentsubsidies           matlab.ui.control.CheckBox
        KWKG                          matlab.ui.control.CheckBox
        EEGUmlage                     matlab.ui.control.CheckBox
        EEGUmlagereduction            matlab.ui.control.CheckBox
        PrivilegedPowerInd            matlab.ui.control.CheckBox
        InvBeh                        matlab.ui.control.CheckBox
        HeatdemandRefurbishmentLabel  matlab.ui.control.Label
        Heatdemand                    matlab.ui.control.DiscreteKnob
        Tab2                          matlab.ui.container.Tab
        LoadFile                      matlab.ui.control.Button
        Label4                        matlab.ui.control.Label
        ShowResults                   matlab.ui.control.Button
        CloseDiagrams                 matlab.ui.control.Button
        UpdateData                    matlab.ui.control.Button
        Label1                        matlab.ui.control.Label
    end


    properties (Access = private)
        FileName % Description
        PathName % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clearvars;
            close all;
        end

        % Button pushed function: Optimize
        function Main(app, event)

            % Value for potential biomass residues
            if app.ResiduesHigh.Value==1
                residues=3;
            elseif app.ResiduesMedium.Value==1
                residues=2;
            elseif app.ResiduesLow.Value==1
                residues=1;
            end    
        
            % Value for potentila energy crops
            if app.CropConst.Value==1
                crop=1;
            elseif app.CropNull.Value==1
                crop=2;
            end
            
            % Value for biomass use for heating
            if app.BioUseHigh.Value==1
                biouse=1;
            elseif app.BioUseLow.Value==1
                biouse=2;
            end
            
            % Value for CO2 price
            if app.P50.Value==1
                CO2=0;
            elseif app.P100.Value==1
                CO2=100;
            elseif app.P200.Value==1
                CO2=200;
            elseif app.P300.Value==1
                CO2=275;
            end
            
            % Value for power price
            if app.PP30.Value==1
                power=32;
            elseif app.PP50.Value==1
                power=52;
            elseif app.PP100.Value==1
                power=120;
            elseif app.PP150.Value==1
                power=215;
            end
            
            % value for used scenario data
            if app.KSS.Value==1
                sc=1;
            elseif app.BMWi.Value==1
                sc=2;
            elseif app.BioStromWaerme.Value==1
                sc=3;
            elseif app.ConstantPrices.Value==1
                sc=4;
            end
            
            Main(app.target.Value,app.Heatdemand.Value,residues,crop,biouse,app.BiomassPriceIncrease.Value,CO2,power,sc,app.Investmentsubsidies.Value,app.KWKG.Value...
                ,app.EEGUmlage.Value,app.EEGUmlagereduction.Value,app.PrivilegedPowerInd.Value,app.InvBeh.Value,app.fig1.Value,app.fig2.Value,app.fig3.Value,app.fig4.Value,app.fig5.Value...
                ,app.fig6.Value,app.fig7.Value,app.fig8.Value,app.fig9.Value,app.fig10.Value,app.fig11.Value,app.fig12.Value,app.fig13.Value,...
                app.fig14.Value,app.fig15.Value,app.figBeh.Value,app.CapTech.Value,app.nMarket.Value,app.nMarketOp.Value,app.save.Value,app.language.Value);
        end

        % Button pushed function: LoadFile
        function LoadFileName(app, event)
            [app.FileName,app.PathName] = uigetfile('*.mat','Select the .mat file');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ShowResults
        function ShResults(app, event)
            Plotting(app.FileName,app.PathName,app.fig1.Value,app.fig2.Value,app.fig3.Value,app.fig4.Value,app.fig5.Value,app.fig6.Value,app.fig7.Value...
                ,app.fig8.Value,app.fig9.Value,app.fig10.Value,app.fig11.Value,app.fig12.Value,app.fig13.Value,app.fig14.Value,app.fig15.Value...
                ,app.figBeh.Value,app.CapTech.Value,app.nMarket.Value,app.nMarketOp.Value,app.language.Value);
        end

        % Button pushed function: CloseDiagrams
        function CloseDia(app, event)
            clearvars;
            close all;
        end

        % Button pushed function: UpdateData
        function xlsx2mat(app, event)
            xlsx2mat();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Optimizing bioenergy use in the German heat sector';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Result Diagrams';
            app.fig1 = uicheckbox(app.Panel);
            app.fig1.Text = 'Market share per Technology type';
            app.fig1.Value = true;
            app.fig2 = uicheckbox(app.Panel);
            app.fig2.Text = 'Technology distribution per sub-sector';
            app.fig2.Value = true;
            app.fig4 = uicheckbox(app.Panel);
            app.fig4.Text = 'Biomass capacity & consumption';
            app.fig4.Value = true;
            app.fig7 = uicheckbox(app.Panel);
            app.fig7.Text = 'Costs';
            app.fig8 = uicheckbox(app.Panel);
            app.fig8.Text = 'GHG';
            app.fig9 = uicheckbox(app.Panel);
            app.fig9.Text = 'Capacity & Production';
            app.fig12 = uicheckbox(app.Panel);
            app.fig12.Text = 'Capacity of modules in Tech:';
            app.fig13 = uicheckbox(app.Panel);
            app.fig13.Text = 'Gas-, power-, CO2-price, yields';
            app.fig14 = uicheckbox(app.Panel);
            app.fig14.Text = 'Operating/ Invest costs in s.-sector';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Language';
            app.language = uidropdown(app.Panel);
            app.language.Items = {'German', 'English'};
            app.language.Value = 'English';
            app.fig11 = uicheckbox(app.Panel);
            app.fig11.Text = 'All n''s in sub-sector:';
            app.nMarket = uieditfield(app.Panel, 'numeric');
            app.nMarket.Limits = [1 19];
            app.nMarket.Value = 5;
            app.CapTech = uieditfield(app.Panel, 'numeric');
            app.CapTech.Limits = [1 47];
            app.CapTech.Value = 16;
            app.fig10 = uicheckbox(app.Panel);
            app.fig10.Text = 'Overcapacity per sub-sector';
            app.nMarketOp = uieditfield(app.Panel, 'numeric');
            app.nMarketOp.Limits = [1 19];
            app.nMarketOp.Value = 1;
            app.fig3 = uicheckbox(app.Panel);
            app.fig3.Text = 'Gas/ coal distribution per technology';
            app.fig5 = uicheckbox(app.Panel);
            app.fig5.Text = 'Biomass in sub-sectors & products';
            app.fig5.Value = true;
            app.fig6 = uicheckbox(app.Panel);
            app.fig6.Text = 'Biomass in technologies';
            app.fig6.Value = true;
            app.fig15 = uicheckbox(app.Panel);
            app.fig15.Text = 'Biomass product prices';
            app.figBeh = uicheckbox(app.Panel);
            app.figBeh.Text = 'Technology distribution per cluster (Beh.)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Optimize';
            app.save = uicheckbox(app.Tab);
            app.save.Text = 'Save results';
            app.Optimize = uibutton(app.Tab, 'push');
            app.Optimize.ButtonPushedFcn = createCallbackFcn(app, @Main, true);
            app.Optimize.Text = 'Optimize';
            app.LabelDiscreteKnob = uilabel(app.Tab);
            app.LabelDiscreteKnob.Text = 'GHG Reduction Target 2050';
            app.target = uiknob(app.Tab, 'discrete');
            app.target.Items = {'No target', '80 %', '88 %', '95 %', '98 %'};
            app.target.Value = '80 %';
            app.scenariodata = uibuttongroup(app.Tab);
            app.scenariodata.Title = 'Gas-, Coal price, power mix emission fact.';
            app.KSS = uiradiobutton(app.scenariodata);
            app.KSS.Text = 'Klimaschutzszenario 2050, 2015';
            app.KSS.Value = true;
            app.BMWi = uiradiobutton(app.scenariodata);
            app.BMWi.Text = 'BMWi Langfristszenarien, 2019';
            app.BioStromWaerme = uiradiobutton(app.scenariodata);
            app.BioStromWaerme.Text = 'BioStromWärme, 2018';
            app.ConstantPrices = uiradiobutton(app.scenariodata);
            app.ConstantPrices.Text = 'Constant prices';
            app.PotentialCrops = uibuttongroup(app.Tab);
            app.PotentialCrops.Title = 'Potential for energy crops until 2050';
            app.CropConst = uiradiobutton(app.PotentialCrops);
            app.CropConst.Text = 'constant 2 Mio ha';
            app.CropConst.Value = true;
            app.CropNull = uiradiobutton(app.PotentialCrops);
            app.CropNull.Text = 'linear to 0 ha';
            app.BiomassUse = uibuttongroup(app.Tab);
            app.BiomassUse.Title = 'Biomass use for heating (incl. CHP)';
            app.BioUseHigh = uiradiobutton(app.BiomassUse);
            app.BioUseHigh.Text = 'high level (~70%)';
            app.BioUseHigh.Value = true;
            app.BioUseLow = uiradiobutton(app.BiomassUse);
            app.BioUseLow.Text = 'low level (~30%)';
            app.PotentialResidues = uibuttongroup(app.Tab);
            app.PotentialResidues.Title = 'Potential biomass residues 2050';
            app.ResiduesHigh = uiradiobutton(app.PotentialResidues);
            app.ResiduesHigh.Text = 'high';
            app.ResiduesHigh.Value = true;
            app.ResiduesLow = uiradiobutton(app.PotentialResidues);
            app.ResiduesLow.Text = 'low';
            app.ResiduesMedium = uiradiobutton(app.PotentialResidues);
            app.ResiduesMedium.Text = 'medium';
            app.BiomasspriceincreaseaEditFieldLabel = uilabel(app.Tab);
            app.BiomasspriceincreaseaEditFieldLabel.Text = 'Biomass price increase [%/a]';
            app.BiomassPriceIncrease = uieditfield(app.Tab, 'text');
            app.BiomassPriceIncrease.Value = '3';
            app.CO2price = uibuttongroup(app.Tab);
            app.CO2price.Title = 'CO2-price in 2050 [€/tCO2eqiuv.]';
            app.P50 = uiradiobutton(app.CO2price);
            app.P50.Text = '0';
            app.P50.Value = true;
            app.P100 = uiradiobutton(app.CO2price);
            app.P100.Text = '100';
            app.P200 = uiradiobutton(app.CO2price);
            app.P200.Text = '200';
            app.P300 = uiradiobutton(app.CO2price);
            app.P300.Text = '275';
            app.PowerPrice = uibuttongroup(app.Tab);
            app.PowerPrice.Title = 'Stock power price in 2050 [€/MWh]';
            app.PP30 = uiradiobutton(app.PowerPrice);
            app.PP30.Text = '32';
            app.PP30.Value = true;
            app.PP50 = uiradiobutton(app.PowerPrice);
            app.PP50.Text = '55';
            app.PP100 = uiradiobutton(app.PowerPrice);
            app.PP100.Text = '120';
            app.PP150 = uiradiobutton(app.PowerPrice);
            app.PP150.Text = '215';
            app.Incentives = uipanel(app.Tab);
            app.Incentives.Title = 'Incentives';
            app.Investmentsubsidies = uicheckbox(app.Incentives);
            app.Investmentsubsidies.Text = 'Investment subsidies';
            app.KWKG = uicheckbox(app.Incentives);
            app.KWKG.Text = 'KWKG (Vergütung & Umlage)';
            app.EEGUmlage = uicheckbox(app.Incentives);
            app.EEGUmlage.Text = 'EEG Umlage';
            app.EEGUmlagereduction = uicheckbox(app.Incentives);
            app.EEGUmlagereduction.Text = 'EEG Umlage reduction';
            app.PrivilegedPowerInd = uicheckbox(app.Incentives);
            app.PrivilegedPowerInd.Text = 'Privileged power price in industry';
            app.InvBeh = uicheckbox(app.Tab);
            app.InvBeh.Text = 'Investment behaviour';
            app.HeatdemandRefurbishmentLabel = uilabel(app.Tab);
            app.HeatdemandRefurbishmentLabel.Text = {'Heat demand'; '(Refurbishment)'};
            app.Heatdemand = uiknob(app.Tab, 'discrete');
            app.Heatdemand.Items = {'Low (n.a.)', '1-2 %', '2-3 %'};
            app.Heatdemand.Value = '1-2 %';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Open results';
            app.LoadFile = uibutton(app.Tab2, 'push');
            app.LoadFile.ButtonPushedFcn = createCallbackFcn(app, @LoadFileName, true);
            app.LoadFile.Text = '...';
            app.Label4 = uilabel(app.Tab2);
            app.Label4.Text = 'Load file:';
            app.ShowResults = uibutton(app.Tab2, 'push');
            app.ShowResults.ButtonPushedFcn = createCallbackFcn(app, @ShResults, true);
            app.ShowResults.Text = 'Show results';
            app.CloseDiagrams = uibutton(app.UIFigure, 'push');
            app.CloseDiagrams.ButtonPushedFcn = createCallbackFcn(app, @CloseDia, true);
            app.CloseDiagrams.Text = 'Close all figures';
            app.UpdateData = uibutton(app.UIFigure, 'push');
            app.UpdateData.ButtonPushedFcn = createCallbackFcn(app, @xlsx2mat, true);
            app.UpdateData.Text = 'Update data (xlsx2mat)';
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = 'BENOPT-HEAT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end